classdef buildTowerGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        RunTab                        matlab.ui.container.Tab
        Button_6                      matlab.ui.control.Button
        SpeedKnobLabel                matlab.ui.control.Label
        SpeedKnob                     matlab.ui.control.DiscreteKnob
        PerformanceSettingsButton     matlab.ui.control.Button
        mmLabel                       matlab.ui.control.Label
        mmLabel_2                     matlab.ui.control.Label
        Label_3                       matlab.ui.control.Label
        XEditFieldLabel               matlab.ui.control.Label
        XEditField                    matlab.ui.control.NumericEditField
        YEditFieldLabel               matlab.ui.control.Label
        YEditField                    matlab.ui.control.NumericEditField
        EditFieldLabel                matlab.ui.control.Label
        thetaEditField                matlab.ui.control.NumericEditField
        ConnectButton                 matlab.ui.control.Button
        STOPButton                    matlab.ui.control.Button
        BuildtowerButton              matlab.ui.control.Button
        NumberofBlocksEditFieldLabel  matlab.ui.control.Label
        NumberofBlocksEditField       matlab.ui.control.NumericEditField
        BuildTimeTextArea             matlab.ui.control.TextArea
        LoadSideSwitch_2Label         matlab.ui.control.Label
        LoadSideSwitch_2              matlab.ui.control.Switch
        DisconnectButton              matlab.ui.control.Button
        RecommendedSettingsButton     matlab.ui.control.Button
        ReadInCurrentPositionButton   matlab.ui.control.Button
        PassiveReadTab                matlab.ui.container.Tab
        Button_8                      matlab.ui.control.Button
        ReadCurrentPositionButton     matlab.ui.control.Button
        XEditField_2Label             matlab.ui.control.Label
        XEditField_2                  matlab.ui.control.NumericEditField
        YEditField_2Label             matlab.ui.control.Label
        YEditField_2                  matlab.ui.control.NumericEditField
        ZEditFieldLabel               matlab.ui.control.Label
        ZEditField                    matlab.ui.control.NumericEditField
        EditFieldLabel_2              matlab.ui.control.Label
        ThetaEditField                matlab.ui.control.NumericEditField
    end


    properties (Access = public)
        serial % Description
    end

    properties (Access = private)
        connectionStatus = 0 % Is robot connected
        run = 0; % Description
    end

    methods (Access = private)
    
        function updateConnectionStatus(app, newStatus)
            % New status: 1 if connected, 0 if not connected
            app.connectionStatus = newStatus;
            if newStatus
                app.serial = selectSerial();
                                
                % Hide connect button
                app.ConnectButton.Enable = 'off'; % disable connect button
                app.ConnectButton.Visible = 'off'; % hide connect button
                
                % Enable Build Button
                app.BuildtowerButton.Enable = 'on'; % Enable build tower button
                
                % Show Disconnect button
                app.DisconnectButton.Enable = 'on';
                app.DisconnectButton.Visible = 'on';
                
                % Enable Read In position button
                app.ReadInCurrentPositionButton.Enable = 'on';
                
            elseif ~newStatus
                app.closeSerial();
                
                % Show Build tower button but disable
                app.BuildtowerButton.Visible = 'on';
                app.BuildtowerButton.Enable = 'off';
                
                % Hide disconnect button
                app.DisconnectButton.Enable = 'off';
                app.DisconnectButton.Visible = 'off';
                
                % Show connect button
                app.ConnectButton.Enable = 'on'; % enable connect button
                app.ConnectButton.Visible = 'on'; % show connect button
                
                % Disable Read In position button
                app.ReadInCurrentPositionButton.Enable = 'off';


            end
        end
        
        function updateRunStatus(app, newRun)
            app.run = newRun; 
            if newRun
                % Hide Build tower button
                app.BuildtowerButton.Visible = 'off';
                app.BuildtowerButton.Enable = 'off';
                
                % Show stop button
                app.STOPButton.Visible = 'on';
                app.STOPButton.Enable = 'on';
                
                % Show Build time 
                app.BuildTimeTextArea.Value = {''};
                app.BuildTimeTextArea.Enable = 'on';
                app.BuildTimeTextArea.Visible = 'on';
                
            elseif ~newRun
                % Disconnect
                app.updateConnectionStatus(0); 
                
                % Hide stop button
                app.STOPButton.Visible = 'off';
                app.STOPButton.Enable = 'off';
            end
        end
        
        function closeSerial(app)
            disp('Quitting')
            fclose(app.serial);
            delete(app.serial);
            clear serial    
        end
        
        function setBuildSettings(app, x,y,theta,loadSide,speed, nBlocks)
            app.XEditField.Value = x;
            app.YEditField.Value = y;
            app.thetaEditField.Value = theta;
            app.LoadSideSwitch_2.Value = loadSide;
            app.SpeedKnob.Value = speed;
            app.NumberofBlocksEditField.Value = nBlocks; 
        end
        
        function s = generateTimeString(app,time)
            mins = floor(time/60); 
            seconds = round(time-mins*60);
%             if mins>0    
%                 s = sprintf('%d mins, %d seconds',mins,seconds);
%             elseif mins==0
%                 s = sprintf('%d seconds',seconds);
%             end
            if seconds<10
                s = sprintf('%d:0%d',mins,seconds); 
            elseif seconds>=10
                s = sprintf('%d:%d',mins,seconds);
            end
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        clear serial
        close all
        debuggingVars();
        end

        % Button pushed function: BuildtowerButton
        function BuildtowerButtonPushed(app, event)
            if app.connectionStatus
                app.updateRunStatus(1);  

                % Read in desired build location 
                x = app.XEditField.Value;
                y = app.YEditField.Value;
                theta = app.thetaEditField.Value;
                
                if y==0 && theta ==0
                    y = -1;
                end
                
                loadSide = app.LoadSideSwitch_2.Value;
                speed = app.SpeedKnob.Value;
                nBlocks = app.NumberofBlocksEditField.Value;
                disp('Send multiple trajectories and run position control...')
                [nchunks, chunks] = createMotionPlan(x,y,theta,loadSide,speed,nBlocks);
                
                start_time = tic;
                
                for i = 1:nchunks
                    pause(0.01);% Interrupts to allow Run Status to update for stop button
                    if app.run
                        data = chunks(:,:,:,i);
                        xdata = data(:,:,1);
                        ydata = data(:,:,2);
                        zdata = data(:,:,3);
                        thdata = data(:,:,4);
                        gripdata = data(:,:,5);
                        [length,~,~] = size(data);
                        
                        sendTrajectory(app.serial, length, xdata, ydata, zdata, thdata, gripdata);
                        
                        disp('Chunk '+string(i)+' of '+string(nchunks)+' sent.')
                        success = runControl(app.serial, "PC", i);
                        
                        s = app.generateTimeString(toc(start_time));
                        app.BuildTimeTextArea.Value = {s};
                        
                        if ~success 
                            app.updateRunStatus(0);
                            error('Run Control Not Successful')
                        end
                    elseif ~(app.run)
                        s = app.generateTimeString(toc(start_time));
                        app.BuildTimeTextArea.Value = {s};
                        return
                    end
                end
                
                end_time = toc(start_time);
                disp('Duration of motion plan: '+string(end_time)+'s');
                s = app.generateTimeString(end_time);
              
                app.BuildTimeTextArea.Value = {s};
               
                app.updateRunStatus(0); 
            end
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            app.updateConnectionStatus(1);
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            app.updateRunStatus(0);
        end

        % Button pushed function: PerformanceSettingsButton
        function PerformanceSettingsButtonPushed(app, event)
            app.setBuildSettings(200,-100,0,'A','Full Jenghis',54);
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            app.updateConnectionStatus(0);  
        end

        % Button pushed function: RecommendedSettingsButton
        function RecommendedSettingsButtonPushed(app, event)
            app.setBuildSettings(200,-50,45,'A','Balanced',54);
        end

        % Value changed function: YEditField
        function YEditFieldValueChanged(app, event)
            value = app.YEditField.Value;
            if value>0
                app.LoadSideSwitch_2.Value = 'B';
            elseif value<=0
                app.LoadSideSwitch_2.Value = 'A';
            end
        end

        % Button pushed function: ReadCurrentPositionButton
        function ReadCurrentPositionButtonPushed(app, event)
            [x,y,z,thx,thy,thz,grip] = readCurrentPosition(app.serial);
            app.XEditField_2.Value = x*1000; 
            app.YEditField_2.Value = y*1000;
            app.ZEditField.Value = z*1000;
            app.ThetaEditField.Value = thz*180/pi;
        end

        % Button pushed function: ReadInCurrentPositionButton
        function ReadInCurrentPositionButtonPushed(app, event)
            [x,y,z,thx,thy,thz,grip] = readCurrentPosition(app.serial);
            app.XEditField.Value = x*1000; 
            app.YEditField.Value = y*1000;
            app.thetaEditField.Value = thz*180/pi;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.RunTab = uitab(app.TabGroup);
            app.RunTab.Title = 'Run';
            app.Button_6 = uibutton(app.RunTab, 'push');
            app.Button_6.Icon = 'GUI_background2.png';
            app.Button_6.Text = '';
            app.SpeedKnobLabel = uilabel(app.RunTab);
            app.SpeedKnobLabel.Text = 'Speed';
            app.SpeedKnob = uiknob(app.RunTab, 'discrete');
            app.SpeedKnob.Items = {'Accurate', 'Balanced', 'Full Jenghis'};
            app.SpeedKnob.Value = 'Balanced';
            app.PerformanceSettingsButton = uibutton(app.RunTab, 'push');
            app.PerformanceSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @PerformanceSettingsButtonPushed, true);
            app.PerformanceSettingsButton.Text = {'Performance'; ' Settings'};
            app.mmLabel = uilabel(app.RunTab);
            app.mmLabel.Text = 'mm';
            app.mmLabel_2 = uilabel(app.RunTab);
            app.mmLabel_2.Text = 'mm';
            app.Label_3 = uilabel(app.RunTab);
            app.Label_3.Text = '°';
            app.XEditFieldLabel = uilabel(app.RunTab);
            app.XEditFieldLabel.Text = 'X: ';
            app.XEditField = uieditfield(app.RunTab, 'numeric');
            app.XEditField.Limits = [0 300];
            app.XEditField.Value = 200;
            app.YEditFieldLabel = uilabel(app.RunTab);
            app.YEditFieldLabel.Text = 'Y: ';
            app.YEditField = uieditfield(app.RunTab, 'numeric');
            app.YEditField.Limits = [-300 300];
            app.YEditField.ValueDisplayFormat = '%.0f';
            app.YEditField.ValueChangedFcn = createCallbackFcn(app, @YEditFieldValueChanged, true);
            app.YEditField.Value = -50;
            app.EditFieldLabel = uilabel(app.RunTab);
            app.EditFieldLabel.Text = 'Ɵ: ';
            app.thetaEditField = uieditfield(app.RunTab, 'numeric');
            app.thetaEditField.Limits = [-45 45];
            app.thetaEditField.Value = 45;
            app.ConnectButton = uibutton(app.RunTab, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.STOPButton = uibutton(app.RunTab, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Enable = 'off';
            app.STOPButton.Visible = 'off';
            app.STOPButton.Text = 'STOP';
            app.BuildtowerButton = uibutton(app.RunTab, 'push');
            app.BuildtowerButton.ButtonPushedFcn = createCallbackFcn(app, @BuildtowerButtonPushed, true);
            app.BuildtowerButton.Enable = 'off';
            app.BuildtowerButton.Text = 'Build tower';
            app.NumberofBlocksEditFieldLabel = uilabel(app.RunTab);
            app.NumberofBlocksEditFieldLabel.Text = {'Number of'; ' Blocks:'};
            app.NumberofBlocksEditField = uieditfield(app.RunTab, 'numeric');
            app.NumberofBlocksEditField.Limits = [1 54];
            app.NumberofBlocksEditField.ValueDisplayFormat = '%.0f';
            app.NumberofBlocksEditField.Value = 54;
            app.BuildTimeTextArea = uitextarea(app.RunTab);
            app.BuildTimeTextArea.Editable = 'off';
            app.BuildTimeTextArea.Visible = 'off';
            app.LoadSideSwitch_2Label = uilabel(app.RunTab);
            app.LoadSideSwitch_2Label.Text = 'Load Side';
            app.LoadSideSwitch_2 = uiswitch(app.RunTab, 'slider');
            app.LoadSideSwitch_2.Items = {'A', 'B'};
            app.LoadSideSwitch_2.Value = 'A';
            app.DisconnectButton = uibutton(app.RunTab, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Enable = 'off';
            app.DisconnectButton.Visible = 'off';
            app.DisconnectButton.Text = 'Disconnect';
            app.RecommendedSettingsButton = uibutton(app.RunTab, 'push');
            app.RecommendedSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @RecommendedSettingsButtonPushed, true);
            app.RecommendedSettingsButton.Text = {'Recommended'; ' Settings'};
            app.ReadInCurrentPositionButton = uibutton(app.RunTab, 'push');
            app.ReadInCurrentPositionButton.ButtonPushedFcn = createCallbackFcn(app, @ReadInCurrentPositionButtonPushed, true);
            app.ReadInCurrentPositionButton.Enable = 'off';
            app.ReadInCurrentPositionButton.Text = {'Read In'; 'Current Position'};
            app.PassiveReadTab = uitab(app.TabGroup);
            app.PassiveReadTab.Title = 'Passive Read';
            app.Button_8 = uibutton(app.PassiveReadTab, 'push');
            app.Button_8.Icon = 'GUI_background3.png';
            app.Button_8.Text = '';
            app.ReadCurrentPositionButton = uibutton(app.PassiveReadTab, 'push');
            app.ReadCurrentPositionButton.ButtonPushedFcn = createCallbackFcn(app, @ReadCurrentPositionButtonPushed, true);
            app.ReadCurrentPositionButton.Text = 'Read Current Position';
            app.XEditField_2Label = uilabel(app.PassiveReadTab);
            app.XEditField_2Label.Text = 'X';
            app.XEditField_2 = uieditfield(app.PassiveReadTab, 'numeric');
            app.XEditField_2.Editable = 'off';
            app.YEditField_2Label = uilabel(app.PassiveReadTab);
            app.YEditField_2Label.Text = 'Y';
            app.YEditField_2 = uieditfield(app.PassiveReadTab, 'numeric');
            app.YEditField_2.Editable = 'off';
            app.ZEditFieldLabel = uilabel(app.PassiveReadTab);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.PassiveReadTab, 'numeric');
            app.ZEditField.Editable = 'off';
            app.EditFieldLabel_2 = uilabel(app.PassiveReadTab);
            app.EditFieldLabel_2.Text = 'Ɵ';
            app.ThetaEditField = uieditfield(app.PassiveReadTab, 'numeric');
            app.ThetaEditField.Editable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = buildTowerGUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end